classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WarningLabel               matlab.ui.control.Label
        RRValue                    matlab.ui.control.Label
        RRLabel                    matlab.ui.control.Label
        HRValue                    matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        ReadingSwitch              matlab.ui.control.Switch
        InterferometerButtonGroup  matlab.ui.container.ButtonGroup
        MZIButton                  matlab.ui.control.RadioButton
        SagnacButton               matlab.ui.control.RadioButton
        RRFltPlot                  matlab.ui.control.UIAxes
        HRFltPlot                  matlab.ui.control.UIAxes
        RawPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        switchStatus
        counter = 0
        dq
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadingSwitch
        function ReadingSwitchValueChanged(app, event)
            value = app.ReadingSwitch.Value;

            % NI DAQ setting
            app.dq = daq("ni");
            if app.SagnacButton.Value
                addinput(app.dq, "Dev4", "ai0", "Voltage");
            else
                addinput(app.dq, "Dev4", "ai1", "Voltage");
                addinput(app.dq, "Dev4", "ai2", "Voltage");
                addinput(app.dq, "Dev4", "ai3", "Voltage");
            end
            app.dq.Rate = 5000;
            
            if value == "On"
                app.InterferometerButtonGroup.Enable = 'off';
                app.switchStatus = true;

                % NI DAQ background acquisition placeholder
                app.dq.ScansAvailableFcnCount = 1250;
                app.dq.ScansAvailableFcn = @(src,evt) plotDataAvailable(src,evt);

                if app.counter == 0
                    scanData = [];
                end

                start(app.dq,"continuous");
                hold(app.RawPlot,"on");
                while app.dq.Running
                    pause(0.1);
                end
                
                
            else
                app.HRValue.Text = "-";
                app.RRValue.Text = "-";
                app.HRValue.FontColor = "k";
                app.RRValue.FontColor = "k";
                app.WarningLabel.Visible = "off";
                cla(app.RawPlot);
                cla(app.HRFltPlot);
                cla(app.RRFltPlot);
                app.InterferometerButtonGroup.Enable = 'on';
                app.switchStatus = false;
                app.dq.stop();
            end

            function plotDataAvailable(src,~)
                [data,timestamps,~] = read(src,src.ScansAvailableFcnCount,"OutputFormat","Matrix");
                p = plot(app.RawPlot,mod(timestamps,30),data);
                p(1).Color = "#0072BD";
                p(2).Color = "#D95319";
                p(3).Color = "#EDB120";
                scanData = [scanData; data];

                if mod(length(scanData),50000) == 0
                    app.counter = app.counter + 1;

                    L = length(scanData);

                    % Sample data for function testing
%                     if app.SagnacButton.Value
%                         loadPVDF = readtable('./Data/Sagnac/Lay_Up_without_anything_Test_1_Jason.xlsx','Range','B:B');
%                         PVDFdata = table2array(loadPVDF);
%                         scanData = PVDFdata(1:end,1);
%                     else
%                         loadPVDF = readtable('./Data/MZI/Lay_Without_Cushion_On_Ref_Jason.xlsx','Range','C:E');
%                         scanData = table2array(loadPVDF);
%                     end

                    % Plot the raw and filtered data into the plot
                    % Show the HR and RR on the interface
                    if app.SagnacButton.Value
                        [HR,RR,hrFlt,rrFlt] = Sagnac_Extraction(scanData,false);
                        plot(app.HRFltPlot,linspace(0,L/5000,L),hrFlt);
                        plot(app.RRFltPlot,linspace(0,L/5000,L),rrFlt);
                        app.HRValue.Text = string(HR);
                        app.RRValue.Text = string(RR);
                    else
                        [HR,RR,hrFlt,rrFlt] = MZI_Extraction(scanData,false);
                        plot(app.HRFltPlot,linspace(0,L/5000,L-1),hrFlt);
                        plot(app.RRFltPlot,linspace(0,L/5000,L-1),rrFlt);
                        app.HRValue.Text = string(HR);
                        app.RRValue.Text = string(RR);
                    end

                    % Change to red color to alert user is HR is abnormal
                    if (HR > 100) || (HR < 45)
                        app.HRValue.FontColor = "r";
                    else
                        app.HRValue.FontColor = "k";
                    end

                    % Change to red color to alert user is RR is abnormal
                    if (RR > 25) || (RR < 15)
                        app.RRValue.FontColor = "r";
                    else
                        app.RRValue.FontColor = "k";
                    end

                    % Show warning message if HR or RR is abnormal
                    if (HR > 100) || (HR < 45) || (RR > 25) || (RR < 15)
                        app.WarningLabel.Visible = "on";
                    else
                        app.WarningLabel.Visible = "off";
                    end
                    % Clear the Raw plot
                    if mod(app.counter,3) == 0
                        cla(app.RawPlot);
                    end
                end

                % Sliding window
                if length(scanData) > 150000
                    scanData(1:50000,:) = [];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FYP HR&RR Extraction';
            app.RawPlot = uiaxes(app.UIFigure);
            title(app.RawPlot, 'Raw BCG Diagram')
            xlabel(app.RawPlot, 'Time (s)')
            ylabel(app.RawPlot, 'Voltage (V)')
            zlabel(app.RawPlot, 'Z')
            app.RawPlot.XLim = [0 30];
            app.HRFltPlot = uiaxes(app.UIFigure);
            title(app.HRFltPlot, 'HR Filtered BCG Diagram')
            xlabel(app.HRFltPlot, 'Time (s)')
            ylabel(app.HRFltPlot, 'Voltage (V)')
            zlabel(app.HRFltPlot, 'Z')
            app.HRFltPlot.XLim = [0 30];
            app.RRFltPlot = uiaxes(app.UIFigure);
            title(app.RRFltPlot, 'RR Filtered BCG Diagram')
            xlabel(app.RRFltPlot, 'Time (s)')
            ylabel(app.RRFltPlot, 'Voltage (V)')
            zlabel(app.RRFltPlot, 'Z')
            app.RRFltPlot.XLim = [0 30];
            app.InterferometerButtonGroup = uibuttongroup(app.UIFigure);
            app.InterferometerButtonGroup.Title = 'Interferometer';
            app.SagnacButton = uiradiobutton(app.InterferometerButtonGroup);
            app.SagnacButton.Text = 'Sagnac';
            app.MZIButton = uiradiobutton(app.InterferometerButtonGroup);
            app.MZIButton.Text = 'MZI';
            app.MZIButton.Value = true;
            app.ReadingSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReadingSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadingSwitchValueChanged, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = 'HR:';
            app.HRValue = uilabel(app.UIFigure);
            app.HRValue.Text = '-';
            app.RRLabel = uilabel(app.UIFigure);
            app.RRLabel.Text = 'RR:';
            app.RRValue = uilabel(app.UIFigure);
            app.RRValue.Text = '-';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'Abnormal detected!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end